classdef inputBoxTest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TestImportLabel           matlab.ui.control.Label
        ImportButton              matlab.ui.control.Button
        ExportNameEditField       matlab.ui.control.EditField
        ExportNameEditFieldLabel  matlab.ui.control.Label
        ExportButton              matlab.ui.control.Button
        AddAlignmentButton        matlab.ui.control.Button
        SelectInitalButton        matlab.ui.control.Button
        PreviewCloudButton        matlab.ui.control.Button
        AlignedListBox            matlab.ui.control.ListBox
        AlignedListBoxLabel       matlab.ui.control.Label
        RawListBox                matlab.ui.control.ListBox
        RawListBoxLabel           matlab.ui.control.Label
        Button                    matlab.ui.control.Button
    end

    
    properties (Access = public)
         RSViews=load('C:\Users\agori\Documents\MATLAB\MSc\appDesign\RSviews.mat');  % Description
         RSViewsAligned=pcviewset;
         %RSData=load('C:\Users\agori\Documents\MATLAB\MSc\appDesign\RSData.mat');
         RSData=load('C:\Users\agori\Documents\MATLAB\MSc\appDesign\ExtractPC\RSpancakeA.mat');
         arrRS_clouds;
         arrRS_aligned;
         pntTforms;
         pcAligned;
         figShow;
         RSset;
         DialogApp;
         num;
         arrOrder;
         file;
         location;
         
    end
    
    methods (Access = public)
        
        function updateClouds(app,pcNew,tform_new,index)
            app.arrRS_aligned{index}=pcNew;
            app.pntTforms{index}=tform_new;
            app.pcAligned=pcmerge(app.pcAligned,pcNew,0.001);
            app.arrOrder=[app.arrOrder,index];
            app.AlignedListBox.Items=string(app.arrOrder);
            %save("testprogram.m",app.arrTforms,app.arrRS_algined);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            %app.arrRS_clouds=app.RSData.arrRS_clouds;
            app.arrRS_clouds=app.RSData.arrRS;
            app.num=length(app.arrRS_clouds);
            app.arrRS_aligned=cell(1,app.num);
            app.pntTforms=cell(1,app.num);
            app.arrOrder=[];
            %dropDown=string([1:app.num]);
            %folderPath = 'C:\Users\agori\Documents\MATLAB\MSc\Validation\2blocks\Realsense-Summary'; 
            %files = dir(folderPath);
            %fileNames = {files.name};
            %app.DropDown.Items=["1","2","3"];
            %app.DropDown.Items=fileNames;
            %app.AlignedListBox.Items=["1","2","3"];
            %app.RawListBox.Items=["1","2","3"];
            app.RawListBox.Items=string([1:app.num]);

            %app.RSViewsAligned
            
        end

        % Button pushed function: PreviewCloudButton
        function PreviewCloudButtonPushed(app, event)
            val = app.RawListBox.Value;
%             app.RSset=app.RSViews.RSvSet;
%             view=findView(app.RSset,str2num(val));
%             pcView=view.PointCloud;
            %app.figShow=showCloud(pcView);
            app.figShow=showCloud(app.arrRS_clouds{str2num(val)});
        end

        % Button pushed function: SelectInitalButton
        function SelectInitalButtonPushed(app, event)
            val = app.RawListBox.Value;
%             app.RSset=app.RSViews.RSvSet;
%             view=findView(app.RSset,str2num(val));
            %pcInit=view.PointCloud;
            i=str2num(val);
            pcInit=app.arrRS_clouds{i};
            app.arrRS_aligned{i}=pcInit;
            %app.RSViewsAligned = addView(app.RSViewsAligned,1,"PointCloud",pcInit);
            app.AlignedListBox.Items={val};
            app.pcAligned=pcInit;
            app.arrOrder=[i];
        end

        % Button pushed function: AddAlignmentButton
        function AddAlignmentButtonPushed(app, event)
           %Get selected cloud - pcMoving
           val = app.RawListBox.Value;
           i=str2num(val);
%            app.RSset=app.RSViews.RSvSet;
%            view=findView(app.RSset,str2num(val));
%            pcMoving=view.PointCloud;
%            index=val;
           pcMoving=app.arrRS_clouds{i};
           app.DialogApp = DialogAppTemplate(app,pcMoving,app.pcAligned,i);
           %Get merged pcAligned

           %Send [pcAligned, pcMoving] to show figs tab

           %in showFigs
            %Get transform
            %Add tform and pc to RSViewsAligned
            %Get transformed pcMovingAligned
            %pcAligned=pcmerge(pcAligned,pcMovingAligned)

           %
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            %dataset = struct('RS_aligned',{}, 'filename', {},'croppedCloud', {});
            vars={app.arrRS_aligned, app.arrOrder, app.pntTforms, app.arrRS_clouds, app.pcAligned};
            %dummydata=1;
            save("C:\Users\agori\Documents\MATLAB\MSc\appDesign\myTest.mat","vars");
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            [app.file,app.location] = uigetfile;
            app.TestImportLabel.Text = strcat(app.location,app.file);
            app.RSData=load(strcat(app.location,app.file));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.RawListBoxLabel = uilabel(app.UIFigure);
            app.RawListBoxLabel.Text = 'Raw';
            app.RawListBox = uilistbox(app.UIFigure);
            app.AlignedListBoxLabel = uilabel(app.UIFigure);
            app.AlignedListBoxLabel.Text = 'Aligned';
            app.AlignedListBox = uilistbox(app.UIFigure);
            app.PreviewCloudButton = uibutton(app.UIFigure, 'push');
            app.PreviewCloudButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewCloudButtonPushed, true);
            app.PreviewCloudButton.Text = 'Preview Cloud';
            app.SelectInitalButton = uibutton(app.UIFigure, 'push');
            app.SelectInitalButton.ButtonPushedFcn = createCallbackFcn(app, @SelectInitalButtonPushed, true);
            app.SelectInitalButton.Text = 'Select Inital';
            app.AddAlignmentButton = uibutton(app.UIFigure, 'push');
            app.AddAlignmentButton.ButtonPushedFcn = createCallbackFcn(app, @AddAlignmentButtonPushed, true);
            app.AddAlignmentButton.Text = 'Add Alignment';
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.ExportNameEditFieldLabel = uilabel(app.UIFigure);
            app.ExportNameEditFieldLabel.Text = 'Export Name';
            app.ExportNameEditField = uieditfield(app.UIFigure, 'text');
            app.ImportButton = uibutton(app.UIFigure, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.TestImportLabel = uilabel(app.UIFigure);
            app.TestImportLabel.Text = 'TestImport';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = inputBoxTest

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end