classdef DialogAppTemplate < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        PointTransformButton  matlab.ui.control.Button
        TextArea              matlab.ui.control.TextArea
        TextAreaLabel         matlab.ui.control.Label
        GetDataButton         matlab.ui.control.Button
        Button                matlab.ui.control.Button
    end


    properties (Access = private) 
        CallingApp % Main app
        fig1,fig2;
        pnts1_matrix, pnts2_matrix;
        count;
        pc1,pc2;
        pcNew;
        tform;
        i;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app, caller, pcMoving, pcFixed, index)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = caller;
            app.pnts1_matrix = zeros(3,5);
            app.pnts2_matrix = zeros(3,5);
            [app.fig1,app.fig2]=showFigs(pcMoving,pcFixed);
            app.count=0;
            app.pc1=pcMoving;
            app.pc2=pcFixed;
            app.i=index;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % Call main app's public function
            %updateplot(app.CallingApp, app.EditField.Value, app.DropDown.Value);
            %updateClouds(app.CallingApp,app.pcNew,app.tform,app.i);
            app.CallingApp.updateClouds(app.pcNew,app.tform,app.i);
            % Delete the dialog box
            delete(app)
        end

        % Close request function: UIFigure
        function DialogAppCloseRequest(app, event)
            % Enable the Plot Opions button in main app
            %app.CallingApp.OptionsButton.Enable = 'on';
            close(app.fig1);
            close(app.fig2);
            % Delete the dialog box 
            delete(app)
            
        end

        % Button pushed function: GetDataButton
        function GetDataButtonPushed(app, event)
            app.count=app.count+1;
            [pnt1, pnt2] = getSinglePointPair(app.fig1, app.fig2);
            app.pnts1_matrix(:,app.count)=pnt1;
            app.pnts2_matrix(:,app.count)=pnt2;
            pnt1_message= "Point1 selection: " + mat2str(pnt1);
            pnt2_message= "Point2 selection: " + mat2str(pnt2);
            mystring=sprintf("Point selection " + string(app.count)+ "\n"+ pnt1_message + "\n"+ pnt2_message+"\n");
            app.TextArea.Value=[app.TextArea.Value;mystring];
        end

        % Button pushed function: PointTransformButton
        function PointTransformButtonPushed(app, event)
            [rot, trans, err]=absoluteOrientationQuaternion(app.pnts1_matrix, app.pnts2_matrix);
            app.tform = rigidtform3d(rot,trans);
            app.pcNew=pctransform(app.pc1,app.tform);
        
            showPair(app.pc1,app.pc2,"Before");
            showPair(app.pcNew,app.pc2,"After");
            %app.CallingApp.updateClouds(app.pcNew,app.tform,app.i);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Options';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @DialogAppCloseRequest, true);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'OK';
            app.GetDataButton = uibutton(app.UIFigure, 'push');
            app.GetDataButton.ButtonPushedFcn = createCallbackFcn(app, @GetDataButtonPushed, true);
            app.GetDataButton.Text = 'GetData';
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.UIFigure);
            app.PointTransformButton = uibutton(app.UIFigure, 'push');
            app.PointTransformButton.ButtonPushedFcn = createCallbackFcn(app, @PointTransformButtonPushed, true);
            app.PointTransformButton.Text = 'PointTransform';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DialogAppTemplate(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)StartupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end